classdef visual_LA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LinearCombinationButton  matlab.ui.control.Button
        pWin                     matlab.ui.control.UIAxes
        InputVectorsPanel        matlab.ui.container.Panel
        scalV                    matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        vLabel                   matlab.ui.control.Label
        wLabel                   matlab.ui.control.Label
        v1                       matlab.ui.control.NumericEditField
        v2                       matlab.ui.control.NumericEditField
        v3                       matlab.ui.control.NumericEditField
        scalW                    matlab.ui.control.NumericEditField
        w1                       matlab.ui.control.NumericEditField
        w2                       matlab.ui.control.NumericEditField
        w3                       matlab.ui.control.NumericEditField
        TogglePanel              matlab.ui.container.Panel
        ThirdDimensionCheckBox   matlab.ui.control.CheckBox
        ScalarsCheckBox          matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: LinearCombinationButton
        function lComb(app, event)
            hold(app.pWin,'off')
        % ########################
        % ### Plotting of V ######
        % ########################
            orig = [0 0];
            vecV = [app.v1.Value, app.v2.Value];
            if app.scalV.Value ~= 0
                vecV = vecV * app.scalV.Value;
            end
            
            dpV = vecV - orig;
            quiver(app.pWin, orig(1),orig(2),dpV(1),dpV(2),0,'LineWidth',2,'Color','r')
            
        % ########################
        % ### Plotting of W ######
        % ########################
            hold(app.pWin,'on')
            vecW = [app.w1.Value, app.w2.Value];
            if app.scalW.Value ~= 0
                vecW = vecW * app.scalW.Value;
            end
            
            dpW = vecW - orig;
            quiver(app.pWin, orig(1),orig(2),dpW(1),dpW(2),0,'LineWidth',2,'Color','g')
            
        % ########################
        % # Plotting of Outcome ##
        % ########################
            vecO = vecV + vecW;
            dpO = vecO - orig;
            quiver(app.pWin, orig(1),orig(2),dpO(1),dpO(2),0,'LineWidth',2,'Color','b')
            
            % Showing the addition
            dpA = vecW;
            quiver(app.pWin, orig(1),orig(2),dpV(1),dpV(2),0,'LineWidth',2,'Color','w','LineStyle',':')
            quiver(app.pWin, vecV(1),vecV(2),dpA(1),dpA(2),0,'LineWidth',2,'Color','k','LineStyle',':')
            
            % Make sure that you are always one away from max and minimum
            minLim = min([orig vecV vecW]) - 1;
            maxLim = max([orig vecV vecW]) + 1;
            xlim(app.pWin,[minLim maxLim])
            ylim(app.pWin,[minLim maxLim])
            grid(app.pWin, 'on')
        end

        % Value changed function: ThirdDimensionCheckBox
        function add3D2(app, event)
            value = app.ThirdDimensionCheckBox.Value;
            if value == 1
                app.v3.Enable = 'on';
                app.w3.Enable = 'on';
            else
                app.v3.Enable = 'off';
                app.w3.Enable = 'off';
            end
        end

        % Value changed function: ScalarsCheckBox
        function toggleScalars(app, event)
            value = app.ScalarsCheckBox.Value;
            if value == 1
                app.scalV.Enable = 'on';
                app.scalW.Enable = 'on';
            else
                app.scalV.Enable = 'off';
                app.scalW.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LinearCombinationButton = uibutton(app.UIFigure, 'push');
            app.LinearCombinationButton.ButtonPushedFcn = createCallbackFcn(app, @lComb, true);
            app.LinearCombinationButton.Text = 'Linear Combination';
            app.pWin = uiaxes(app.UIFigure);
            title(app.pWin, '')
            xlabel(app.pWin, '')
            ylabel(app.pWin, '')
            app.InputVectorsPanel = uipanel(app.UIFigure);
            app.InputVectorsPanel.Title = 'Input Vectors';
            app.scalV = uieditfield(app.InputVectorsPanel, 'numeric');
            app.scalV.Limits = [-9 9];
            app.scalV.Enable = 'off';
            app.Label = uilabel(app.InputVectorsPanel);
            app.Label.Text = '+';
            app.vLabel = uilabel(app.InputVectorsPanel);
            app.vLabel.Text = 'v';
            app.wLabel = uilabel(app.InputVectorsPanel);
            app.wLabel.Text = 'w';
            app.v1 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.v1.Limits = [-9 9];
            app.v2 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.v2.Limits = [-9 9];
            app.v3 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.v3.Limits = [-9 9];
            app.v3.Enable = 'off';
            app.scalW = uieditfield(app.InputVectorsPanel, 'numeric');
            app.scalW.Limits = [-9 9];
            app.scalW.Enable = 'off';
            app.w1 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.w1.Limits = [-9 9];
            app.w2 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.w2.Limits = [-9 9];
            app.w3 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.w3.Limits = [-9 9];
            app.w3.Enable = 'off';
            app.TogglePanel = uipanel(app.UIFigure);
            app.TogglePanel.Title = 'Toggle';
            app.ThirdDimensionCheckBox = uicheckbox(app.TogglePanel);
            app.ThirdDimensionCheckBox.ValueChangedFcn = createCallbackFcn(app, @add3D2, true);
            app.ThirdDimensionCheckBox.Text = 'Third Dimension';
            app.ScalarsCheckBox = uicheckbox(app.TogglePanel);
            app.ScalarsCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggleScalars, true);
            app.ScalarsCheckBox.Text = 'Scalars';
        end
    end

    methods (Access = public)

        % Construct app
        function app = visual_LA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end